classdef MainAcquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel1                      matlab.ui.container.Panel
        HomerDropDownLabel          matlab.ui.control.Label
        HomerDropDown               matlab.ui.control.DropDown
        ThoraxDropDownLabel         matlab.ui.control.Label
        ThoraxDropDown              matlab.ui.control.DropDown
        HomerSwitch                 matlab.ui.control.Switch
        ThoraxSwitch                matlab.ui.control.Switch
        SensorizedSideListBoxLabel  matlab.ui.control.Label
        SensorizedSideListBox       matlab.ui.control.ListBox
        Panel2                      matlab.ui.container.Panel
        AutoStopSwitch              matlab.ui.control.Switch
        AutoStopEditFieldLabel      matlab.ui.control.Label
        AutoStopEditField           matlab.ui.control.EditField
        SubjectEditFieldLabel       matlab.ui.control.Label
        SubjectEditField            matlab.ui.control.EditField
        GoldStandardEditFieldLabel  matlab.ui.control.Label
        GoldStandardEditField       matlab.ui.control.EditField
        CommentsTextAreaLabel       matlab.ui.control.Label
        CommentsTextArea            matlab.ui.control.TextArea
        ScanButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        PlotButton                  matlab.ui.control.Button
        ISOButton                   matlab.ui.control.Button
        PIEButton                   matlab.ui.control.Button
        HomerUIAxes                 matlab.ui.control.UIAxes
        ThoraxUIAxes                matlab.ui.control.UIAxes
        ButtonGroup                 matlab.ui.container.ButtonGroup
        Projection1Button           matlab.ui.control.ToggleButton
        Projection2Button           matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        SensorsFound = 0;
        ExelObj
        Saved = false;
        Calibration = eye(3);
        Algorithm = @projection;
    end
    
    methods (Static, Access = private)
        
        function SamplingFcn(obj)
            % getting init and stop
            init = obj.LastFrame+1;
            stop = height(obj.ExelData);
            
            % computing indexes and time
            ind = (init : stop)';
            time = (ind-1)/obj.SamplingFrequency;
            
            % setting new XLim if necessary
            if time(end,1) > obj.ExelFigure.Axes.XLim(1,2)
                obj.ExelFigure.Axes.XLim = obj.ExelFigure.Axes.XLim + 10;
                clearpoints(obj.ExelFigure.Line)
            end
            
            % computing Angle
            theta = obj.UserData.Algorithm(obj,ind);
            
            % adding points to the animatedline
            addpoints(obj.ExelFigure.Line,time,theta);
            
            % showing now new values
            drawnow limitrate
            
            % updating LastFrame
            obj.LastFrame = stop;
        end
        
    end
    
    methods (Access = private)
        
        function saveRoutine(app)
            % putting shadow on components
            uishadow(app,'Saving...')
            
            % getting current script directory
            csd = fileparts(mfilename('fullpath'));
            
            % computing data dir
            datapath = fullfile(csd, ...
                char(datetime('now','Format','yyyy-MM-dd')));
            
            % checking datapath existance
            touch(datapath);
            
            % getting Subject
            Subject = app.ExelObj(1,1).Subject;
            
            % setting default filename
            ProgrNum = '01';
            filename = [Subject,'_',ProgrNum,'.mat'];
            while exist(fullfile(datapath,filename),'file')
                ProgrNum = num2str(str2double(ProgrNum)+1);
                if length(ProgrNum)<2
                    ProgrNum = ['0',ProgrNum]; %#okAGROW
                end
                filename = [Subject,'_',ProgrNum,'.mat'];
            end
            
            % getting Exel obj
            obj = ExelCompressed(app.ExelObj); %#okAGROW
            
            % saving
            save(fullfile(datapath,filename),'obj');
            
            % setting saved property to true
            app.Saved = true;
            
            % removing shadow from components
            pause(2)
            uishadow(app)
        end
        
        function enable(app)
            % getting app properties
            appProp = setdiff(properties(app), ...
                {'StartButton','StopButton','SaveButton', ...
                'PlotButton','ISOButton','PIEButton'});
            
            % setting exceptions
            exceptions = {'HomerDropDown';'ThoraxDropDown';'AutoStopEditField'};
            
            % cycling to enable properties
            for i = 1:numel(appProp)
                cProp = appProp{i,1};
                if ismember(cProp,exceptions)
                    temp = strrep(cProp,'DropDown','');
                    temp = strrep(temp,'EditField','');
                    cSwitch = [temp,'Switch'];
                    if strcmp(app.(cSwitch).Value,'On')
                        app.(cProp).Enable = 'on';
                    end
                else
                    try %#okAGROW
                        app.(cProp).Enable = 'on';
                    end
                end
            end
            
            % StartButton
            hValue = string(app.HomerDropDown.Value);
            tValue = string(app.ThoraxDropDown.Value);
            if any([hValue,tValue]~='Select...')
                app.StartButton.Enable = 'on';
            end
            
            % Save and Plot Buttons
            if isa(app.ExelObj,'Exel') && ...
                    height(app.ExelObj(1).ExelData)>0
                app.PlotButton.Enable = 'on';
                if all([hValue,tValue]~='Select...')
                    app.ISOButton.Enable = 'on';
                    app.PIEButton.Enable = 'on';
                end
                if not(app.Saved)
                    app.SaveButton.Enable = 'on';
                end
            end
        end
        
        function disable(app)
            appProp = properties(app);
            for i = 1:numel(appProp)
                cProp = appProp{i,1};
                if not(isa(app.(cProp),'matlab.ui.control.Label'))
                    try %#okAGROW
                        app.(cProp).Enable = 'off';
                    end
                end
            end
        end
        
        function reset(app)
            if isa(app.ExelObj,'Exel')
                for i = 1:numel(app.ExelObj)
                    reset(app.ExelObj(i,1))
                    delete(app.ExelObj(i,1).ExelFigure.Line)
                    app.ExelObj(i,1).ExelFigure.Axes.XLim = [0,10];
                end
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = centerfig('screen',app.UIFigure);
            uishadow(app,{'Checking old','Bluetooth instances...'})
            clc
            instruments = instrfindall;
            for i = 1:numel(instruments)
                try %#okAGROW
                    fclose(instruments(i));
                end
            end
            pause(2)
            ScanButtonPushed(app)
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            % disabling all (except for labels)
            disable(app)
            
            % resetting ExelData and plots
            reset(app)
            
            % resetting ExelNames and Segments
            app.HomerDropDown.Items = {'Select...'};
            app.ThoraxDropDown.Items = {'Select...'};
            app.HomerSwitch.Value = 'Off';
            app.ThoraxSwitch.Value = 'Off';
            
            % putting shadow on components
            uishadow(app,{'Scanning for';'Bluetooth devices...'})
            
            % getting bluetooth info
            try
                btInfo = instrhwinfo('bluetooth');
            catch ME
                uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                app.ScanButton.Enable = 'on';
                return
            end
            
            % getting bluetooth devices friendly names
            btNames = btInfo.RemoteNames;
            if isempty(btNames), btNames = {}; end
            
            % getting friendly names length
            btLengs = cellfun(@length,btNames);
            
            % removing too short or too long names
            btNames(btLengs<5 & btLengs>10) = [];
            
            % finding friendly names who start with EXLs3
            btInits = cellfun(@(x)x(1:5),btNames,'UniformOutput',false);
            exelInd = cellfun(@isequal,btInits,repmat({'EXLs3'},size(btInits)));
            
            % getting exelnames
            exelNames = sort(btNames(exelInd));
            
            % setting the SensorsFound property
            app.SensorsFound = numel(exelNames);
            
            % enabling components if necessary
            if app.SensorsFound > 0
                % showing EXLs3 devices found
                uishadow(app,[exelNames;'had been found!'])
                
                % setting dropdown items
                Items = [{'Select...'};exelNames];
                app.HomerDropDown.Items = Items;
                app.ThoraxDropDown.Items = Items;
                
                % removing shadow from components after 2 sec
                pause(2)
                enable(app)
                uishadow(app)
            else
                % removing shadow from components
                uishadow(app)
                
                % alerting user
                message = ['No EXLs3 sensor found. ', ...
                    'Be sure that the devices ', ...
                    'you want to use are turned on, ', ...
                    'then retry the scan routine.'];
                title = 'No Sensor Found';
                uialert2(app.UIFigure,message,title,'Icon','Warning')
                
                % enabling only scan button
                app.ScanButton.Enable = 'on';
            end
        end

        % Value changed function: HomerSwitch
        function HomerSwitchValueChanged(app, event)
            switch app.HomerSwitch.Value
                case 'On'
                    if app.SensorsFound == 1
                        app.ThoraxSwitch.Value = 'Off';
                        app.ThoraxSwitch.Enable = 'on';
                        app.ThoraxDropDown.Value = 'Select...';
                        app.ThoraxDropDown.Enable = 'off';
                    end
                    app.HomerDropDown.Enable = 'on';
                case 'Off'
                    if ~strcmp(app.HomerDropDown.Value,'Select...')
                        app.ThoraxDropDown.Items = ['Select...'; ...
                            sort([app.ThoraxDropDown.Items(2:end)'; ...
                            {app.HomerDropDown.Value}])];
                    end
                    app.ThoraxSwitch.Enable = 'on';
                    app.HomerDropDown.Value = 'Select...';
                    app.HomerDropDown.Enable = 'off';
            end
            
            % checking dropdown values:
            % if both are not select, then disable StartButton
            hValue = string(app.HomerDropDown.Value);
            tValue = string(app.ThoraxDropDown.Value);
            if all([hValue,tValue]=='Select...')
                app.StartButton.Enable = 'off';
            end
        end

        % Value changed function: ThoraxSwitch
        function ThoraxSwitchValueChanged(app, event)
            switch app.ThoraxSwitch.Value
                case 'On'
                    if app.SensorsFound == 1
                        app.HomerSwitch.Value = 'Off';
                        app.HomerSwitch.Enable = 'on';
                        app.HomerDropDown.Value = 'Select...';
                        app.HomerDropDown.Enable = 'off';
                    end
                    app.ThoraxDropDown.Enable = 'on';
                case 'Off'
                    if ~strcmp(app.ThoraxDropDown.Value,'Select...')
                        app.HomerDropDown.Items = ['Select...'; ...
                            unique([app.HomerDropDown.Items(2:end)'; ...
                            {app.ThoraxDropDown.Value}])];
                    end
                    app.HomerSwitch.Enable = 'on';
                    app.ThoraxDropDown.Value = 'Select...';
                    app.ThoraxDropDown.Enable = 'off';
            end
            
            % checking dropdown values:
            % if both are not select, then disable StartButton
            hValue = string(app.HomerDropDown.Value);
            tValue = string(app.ThoraxDropDown.Value);
            if all([hValue,tValue]=='Select...')
                app.StartButton.Enable = 'off';
            end
        end

        % Value changed function: HomerDropDown
        function HomerDropDownValueChanged(app, event)
            % managing DropDown items
            cValue = string(event.Value);
            pValue = string(event.PreviousValue);
            if app.SensorsFound>1
                if pValue == 'Select...' && cValue == 'Select...'
                    % nothing happens
                elseif pValue == 'Select...' && cValue ~= 'Select...'
                    app.ThoraxDropDown.Items = setdiff( ...
                        app.ThoraxDropDown.Items, ...
                        char(cValue),'stable');
                elseif pValue ~= 'Select...' && cValue == 'Select...'
                    app.ThoraxDropDown.Items = ['Select...'; ...
                        sort([app.ThoraxDropDown.Items(2:end)'; ...
                        {char(pValue)}])];
                elseif pValue ~= 'Select...' && cValue ~= 'Select...'
                    app.ThoraxDropDown.Items = setdiff( ...
                        app.ThoraxDropDown.Items, ...
                        char(cValue),'stable');
                    app.ThoraxDropDown.Items = ['Select...'; ...
                        sort([app.ThoraxDropDown.Items(2:end); ...
                        {char(pValue)}])];
                end
            end
            
            % checking dropdown values to enable StartButton
            hValue = cValue;
            tValue = string(app.ThoraxDropDown.Value);
            if all([hValue,tValue]=='Select...')
                app.StartButton.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
            end
        end

        % Value changed function: ThoraxDropDown
        function ThoraxDropDownValueChanged(app, event)
            % resetting calibration matrix
            app.Calibration = eye(3);
            
            % managing DropDown itmes
            cValue = string(event.Value);
            pValue = string(event.PreviousValue);
            if app.SensorsFound>1
                if pValue == 'Select...' && cValue == 'Select...'
                    % nothing happens
                elseif pValue == 'Select...' && cValue ~= 'Select...'
                    app.HomerDropDown.Items = setdiff( ...
                        app.HomerDropDown.Items, ...
                        char(cValue),'stable');
                elseif pValue ~= 'Select...' && cValue == 'Select...'
                    app.HomerDropDown.Items = ['Select...'; ...
                        sort([app.HomerDropDown.Items(2:end)'; ...
                        {char(pValue)}])];
                elseif pValue ~= 'Select...' && cValue ~= 'Select...'
                    app.HomerDropDown.Items = setdiff( ...
                        app.HomerDropDown.Items, ...
                        char(cValue),'stable');
                    app.HomerDropDown.Items = ['Select...'; ...
                        sort([app.HomerDropDown.Items(2:end); ...
                        {char(pValue)}])];
                end
            end
            
            % checking dropdown values to enable StartButton
            hValue = string(app.HomerDropDown.Value);
            tValue = cValue;
            if all([hValue,tValue]=='Select...')
                app.StartButton.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
            end
        end

        % Value changed function: AutoStopSwitch
        function AutoStopSwitchValueChanged(app, event)
            switch app.AutoStopSwitch.Value
                case 'On'
                    app.AutoStopEditField.Value = '10';
                    app.AutoStopEditField.Enable = 'on';
                case 'Off'
                    app.AutoStopEditField.Value = 'Never Ending';
                    app.AutoStopEditField.Enable = 'off';
            end
        end

        % Value changed function: AutoStopEditField
        function AutoStopEditFieldValueChanged(app, event)
            value = round(str2double(app.AutoStopEditField.Value));
            if value > 0
                app.AutoStopEditField.Value = num2str(value);
            else
                message = 'AutoStop must be a numeric value greater than 0.';
                title = 'AutoStop Invalid Value';
                uialert2(app.UIFigure,message,title)
                app.AutoStopEditField.Value = event.PreviousValue;
            end
        end

        % Value changed function: GoldStandardEditField
        function GoldStandardEditFieldValueChanged(app, event)
            value = round(str2double(app.GoldStandardEditField.Value));
            if value>=0 && value<=180
                app.GoldStandardEditField.Value = num2str(value);
            else
                message = 'GoldStandard must be a numeric value between 0 and 180.';
                title = 'GoldStandard Invalid Value';
                uialert2(app.UIFigure,message,title)
                app.GoldStandardEditField.Value = 'Not Specified';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % disabling all
            disable(app)
            
            % reset ExelData and old plots
            reset(app)
            
            % putting a shadow on components
            uishadow(app,'Creating Exel Objects...')
            
            % getting ExelNames
            ExelNames = setdiff( ...
                {app.HomerDropDown.Value; ...
                app.ThoraxDropDown.Value}, ...
                'Select...','stable');
            
            % getting AutoStop
            if strcmp(app.AutoStopSwitch.Value,'Off')
                AutoStop = Inf;
            else
                AutoStop = str2double(app.AutoStopEditField.Value);
            end
            
            % creating Exel array
            try
                app.ExelObj = Exel(ExelNames,'AutoStop',AutoStop);
            catch ME
                uishadow(app)
                uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                enable(app)
                return
            end
            
            % getting homer and thorax index
            hPresence = ~strcmp(app.HomerDropDown.Value,'Select...');
            tPresence = ~strcmp(app.ThoraxDropDown.Value,'Select...');
            if hPresence==false && tPresence==false
                % impossible
            elseif hPresence==false && tPresence==true
                hIndex = []; tIndex = 1;
            elseif hPresence==true && tPresence==false
                hIndex = 1; tIndex = [];
            elseif hPresence==true && tPresence==true
                hIndex = 1; tIndex = 2;
            end
            
            % setting Segment
            set(app.ExelObj(hIndex),'Segment','Homer')
            set(app.ExelObj(tIndex),'Segment','Thorax')
            
            % setting UserData
            UserData.GoldStandard = str2double(app.GoldStandardEditField.Value);
            UserData.Comments = app.CommentsTextArea.Value;
            UserData.isHomerRight = strcmp(app.SensorizedSideListBox.Value,'Right');
            UserData.Algorithm = app.Algorithm;
            set(app.ExelObj(hIndex),'UserData',UserData);
            set(app.ExelObj(tIndex),'UserData',UserData);
            
            % setting SamplingFcn
            set(app.ExelObj(hIndex),'SamplingFcn',@app.SamplingFcn)
            set(app.ExelObj(tIndex),'SamplingFcn',@app.SamplingFcn)
            
            % creating homer ExelFigure structure
            hFigure.Axes = app.HomerUIAxes;
            hFigure.Line = animatedline(app.HomerUIAxes,'Color',[1,0,0]);
            
            % creating thorax ExelFigure structure
            tFigure.Axes = app.ThoraxUIAxes;
            tFigure.Line = animatedline(app.ThoraxUIAxes,'Color',[0,0,1]);
            
            % setting both ExelFigures
            set(app.ExelObj(hIndex),'ExelFigure',hFigure)
            set(app.ExelObj(tIndex),'ExelFigure',tFigure)
            
            % getting Subject
            if strcmp(app.SubjectEditField.Value,'Not Specified')
                Subject = 'NS00';
            else
                Subject = app.SubjectEditField.Value;
            end
            
            % setting Subject
            set(app.ExelObj,'Subject',Subject)
            
            % setting prev calibration
            set(app.ExelObj(tIndex),'Calibration',app.Calibration)
            
            % determining if calibration routine is necessary
            isCalibrationNecessary = tPresence && isequal(app.Calibration,eye(3));
            
            % calibration routine
            if isCalibrationNecessary
                % removing shadow from components
                uishadow(app)
                
                % asking the user if ready
                selection = uiconfirm( ...
                    app.UIFigure, ...
                    sprintf(['In order to be accurate, it is necessary ', ...
                    'to calibrate the Thorax sensor. The calibration ', ...
                    'routine consists of 3 seconds in wich the subject ', ...
                    'must be standing still.\n\nAre you ready for it?']), ...
                    'Calibration Dialog', ...
                    'Options',{'Yes','Skip','Quit'}, ...
                    'DefaultOption',1, ...
                    'CancelOption',3 ...
                    );
                switch selection
                    case 'Yes'
                        % putting shadow on components
                        uishadow(app,'Connecting for calibration...')
                        
                        % getting right Exel obj
                        obj = app.ExelObj(tIndex);
                        
                        % connecting for calibration
                        try
                            connect(obj)
                        catch ME
                            uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                            StopButtonPushed(app)
                            enable(app)
                            return
                        end
                        
                        % getting AutoStop and replacing it with 10 s
                        cAutoStop = get(obj,'AutoStop');
                        set(obj,'AutoStop',3)
                        
                        % launching calibration: calibration
                        uishadow(app,{'Calibration in...';'3'}),pause(1)
                        uishadow(app,{'Calibration in...';'2'}),pause(1)
                        uishadow(app,{'Calibration in...';'1'}),pause(1)
                        
                        % starting acquisition for calibration
                        uishadow(app,'Starting Calibration...')
                        try
                            start(obj)
                        catch ME
                            uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                            StopButtonPushed(app)
                            enable(app)
                            return
                        end
                        
                        % waiting for the calibration end
                        uishadow(app,'Calibrating... Be still!')
                        waitfor(obj,'AcquisitionStatus','off')
                        
                        % ending the calibration
                        uishadow(app,'Calibration Done!')
                        
                        % filtering
                        obj.ExelData{:,3:5} = ...
                            filterImuData(obj.ExelData{:,3:5}, ...
                            obj.SamplingFrequency);
                        
                        % computing mean angle
                        a = mean(projection(obj));
                        
                        % computing rotation matrix
                        R = [1,0,0;0,cosd(a),sind(a);0,-sind(a),cosd(a)];
                        
                        % setting calibration property
                        app.Calibration = R;
                        
                        % setting the new calibration matrix on Exel object
                        set(obj,'Calibration',R)
                        
                        % resetting AutoStop
                        set(obj,'AutoStop',cAutoStop)
                        
                        % resetting ExelData and LastFrame
                        reset(obj)
                        
                        % resetting plots
                        clearpoints(obj.ExelFigure.Line)
                        obj.ExelFigure.Axes.XLim = [0,10];
                        
                        % making a rest
                        pause(2)
                    case 'Skip'
                        % nothing happens
                    case 'Quit'
                        StopButtonPushed(app)
                        enable(app)
                        return
                end
            end
            
            % connecting exel devices
            uishadow(app,'Connecting...')
            try
                connect(app.ExelObj)
            catch ME
                uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                StopButtonPushed(app)
                enable(app)
                return
            end
            
            % enabling Stop Button
            app.StopButton.Enable = 'on';
            
            % starting acquisition
            try
                start(app.ExelObj)
            catch ME
                uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                StopButtonPushed(app)
                enable(app)
                return
            end
            
            % removing shadow from components
            uishadow(app)
            
            % waiting for the acquisition end
            waitfor(app.ExelObj(end,1),'AcquisitionStatus','off')
            if strcmp(app.ExelObj(end,1).ConnectionStatus,'open')
                StopButtonPushed(app)
            end
            
            % in any case, disabling Stop Button
            app.StopButton.Enable = 'off';
            
            % asking the user for saving
            selection = uiconfirm( ...
                app.UIFigure, ...
                'Do you want to save the entire data collected?', ...
                'Confirm Save', ...
                'Options',{'Yes';'No'}, ...
                'DefaultOption',1, ...
                'CancelOption',2 ...
                );
            
            % saving if necessary
            if strcmp(selection,'Yes')
                saveRoutine(app)
            end
            
            % enabling components
            enable(app)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if isa(app.ExelObj,'Exel')
                uishadow(app,'Disconnecting...')
                try
                    disconnect(app.ExelObj)
                    uishadow(app)
                catch ME
                    uishadow(app)
                    uialert2(app.UIFigure,ME.message,'Bluetooth Error')
                end
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % getting ExelObj as an ExelCompressed one
            obj = ExelCompressed(app.ExelObj);
            
            % getting sf and t0
            sf = obj(1,1).SamplingFrequency;
            t0 = 1/sf;
            
            % presence
            ok_hum = any(strcmp(get(obj,'Segment'), 'Homer')); % Homer
            ok_thx = any(strcmp(get(obj,'Segment'),'Thorax')); % Thorax
            ok_jnt = ok_hum & ok_thx; % Joint
            
            % indexes
            if ~ok_hum
                thx_index = 1;
            elseif ok_thx
                thx_index = 2;
            end
            
            % synchronization routine
            synchronize(obj,inf); % by marta
            h = height(obj(1,1).ExelData);
            
            % preallocating angles and time
            theta = cell(3,1);
            times = cell(3,1);
            
            % computing angles
            if ok_hum
                theta{1,1} = filterImuData(app.Algorithm(obj(1,1)),sf);
                times{1,1} = duration(0,0,(0:h-1)*t0);
            else
                theta{1,1} = [];
                times{1,1} = [];
            end
            if ok_thx
                theta{2,1} = filterImuData(app.Algorithm(obj(thx_index,1)),sf);
                times{2,1} = duration(0,0,(0:h-1)*t0);
            else
                theta{2,1} = [];
                times{2,1} = [];
            end
            if ok_jnt
                theta{3,1} = abs(theta{1,1} + theta{2,1});
                times{3,1} = times{1,1};
            else
                theta{3,1} = [];
                times{3,1} = [];
            end
            
            % other plotting stuff
            Title = {'Homer';'Thorax';'Joint'};
            Color = [1,0,0;0,0,1;0,0,0];
            YLim = [0,180;-90,90;0,180];
            YTick = [0,30,60,90,120,150,180; ...
                -90,-60,-30,0,30,60,90; ...
                0,30,60,90,120,150,180];
            
            % creating figure
            figure('Position',get(0,'ScreenSize'))
            
            % preallocating axes
            ax(3,1) = axes();
            
            % cycling on Exel objects
            for i = 1:3
                % plotting
                ax(i,1) = subplot(3,1,i);
                plot(times{i,1},theta{i,1},'Color',Color(i,:))
                
                % setting some other properties
                xlabel('time')
                ylabel('angle (deg)')
                ylim(YLim(i,:))
                title(Title{i,:})
                set(ax(i,1),'YTick',YTick(i,:))
                set(ax(i,1),'XMinorGrid','on')
                set(ax(i,1),'YMinorGrid','on')
                set(ax(i,1),'XMinorTick','on')
                set(ax(i,1),'YMinorTick','on')
                set(ax(i,1),'FontSize',20)
            end
            
            % linking axes
            linkaxes(ax,'x')
        end

        % Value changed function: SubjectEditField
        function SubjectEditFieldValueChanged(app, event)
            value = app.SubjectEditField.Value;
            if not(length(value)==4 && ...
                    all(isstrprop(value(1:2),'alpha')) && ...
                    all(isstrprop(value(3:4),'digit')))
                message = sprintf(['Subject value must be 4 character', ...
                    ' long and composed by:\n', ...
                    '- first and second characters must be the', ...
                    ' initials of the subject name;\n', ...
                    '- third and fourth characters must be the', ...
                    ' last two digits of the subject birth year.\n\n', ...
                    'Example:\nJS94 for John Smith, born in 1994.']);
                title = 'Subject Invalid Value';
                uialert2(app.UIFigure,message,title)
                app.SubjectEditField.Value = 'Not Specified';
            else
                % resetting calibration matrix
                app.Calibration = eye(3);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveRoutine(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            StopButtonPushed(app)
            delete(app)
        end

        % Button pushed function: ISOButton
        function ISOButtonPushed(app, event)
            % getting ExelObj as an ExelCompressed one
            obj = ExelCompressed(app.ExelObj);
            
            % getting sf and t0
            sf = obj(1,1).SamplingFrequency;
            t0 = 1/sf;
            
            % setting thresholds
            omega_th = 10; % deg/s
            time_th = 4; % s
            
            % synchronization routine
            synchronize(obj,inf); % by marta
            h = height(obj(1,1).ExelData);
            
            % computing absolute angles (with gravity) with projection algorithm
            theta_hum = filterImuData(app.Algorithm(obj(1,1)),sf);
            theta_thx = filterImuData(app.Algorithm(obj(2,1)),sf);
            
            % computing joint angle as a sum of the two above
            theta = abs(theta_hum + theta_thx);
            
            % computing angular velocity
            omega = discDerivative(theta,t0);
            
            % getting under thresholds indexes (static position)
            ind = (abs(omega) < omega_th)'; % row array
            
            % getting ind rises and falls
            rises = strfind(ind,[0,1])+1;
            falls = strfind(ind,[1,0])+1;
            if isempty(rises),rises = 1;end
            if isempty(falls),falls = h;end
            if falls(1,1) < rises(1,1)
                rises = [1,rises];
            end
            if rises(1,end) > falls(1,end)
                falls = [falls,h];
            end
            
            % now we have a static period between a rise and a fall index,
            % so we can compute static period durations as:
            times = (falls-rises)*t0;
            
            % getting only periods longer than 4 seconds
            times_ok_ind = (times>time_th)'; % col array
            rises_ok_ind = rises(1,times_ok_ind)';
            falls_ok_ind = falls(1,times_ok_ind)';
            times = times(times_ok_ind)'./60; % converion from s to min
            
            % creating ISO table
            RowNames = ...
                {'LessThan20';'Between20and30';'Between30and40'; ...
                'Between40and50';'Between50and60';'MoreThan60'};
            VariableNames = {'TimeArray','Occurrences','TotalTime','Mean','DevStd'};
            ISO = cell2table(cell(numel(RowNames),1), ...
                'VariableNames',VariableNames(1,1), ...
                'RowNames',RowNames);
            ISO = [ISO,array2table(zeros(numel(RowNames),numel(VariableNames)-1), ...
                'VariableNames', VariableNames(1,2:end), ...
                'RowNames',RowNames)];
            
            % cycling on periods
            for i = 1:numel(times)
                % getting current rise and fall indexes
                cRise = rises_ok_ind(i,1);
                cFall = falls_ok_ind(i,1);
                
                % getting current angle as the mean in this period
                % getting also current period duration
                cAngle = mean(theta(cRise:cFall));
                cDurat = times(i,1);
                
                % determining right bin
                if cAngle < 20
                    cRow = 'LessThan20';
                elseif cAngle < 30
                    cRow = 'Between20and30';
                elseif cAngle < 40
                    cRow = 'Between30and40';
                elseif cAngle < 50
                    cRow = 'Between40and50';
                elseif cAngle < 60
                    cRow = 'Between50and60';
                else
                    cRow = 'MoreThan60';
                end
                
                % updating ISO tab
                ISO{cRow,'TimeArray'}{1,1} = cat(1,ISO{cRow,'TimeArray'}{1,1},cDurat);
                ISO{cRow,'Occurrences'} = ISO{cRow,'Occurrences'} + 1;
                ISO{cRow,'TotalTime'} = ISO{cRow,'TotalTime'} + cDurat;
                ISO{cRow,'Mean'} = mean(ISO{cRow,'TimeArray'}{1,1});
                ISO{cRow,'DevStd'} = std(ISO{cRow,'TimeArray'}{1,1});
            end
            clear cRise cFall cAngle cDurat cRow
            
            % creating ISOx and ISOy
            ISOx = [10;25;35;45;55;70];
            ISOy = ISO.TotalTime;
            
            % computing chart max height
            h = round(max(ISOy),-1)+10;
            
            % plotting iso chart
            figure('Position',get(0,'ScreenSize')), hold on
            axis([0 80 0 h])
            patch([ 0 20 20  0],[0 0 h h],[0 1 0],'EdgeColor','none','FaceAlpha',0.7)
            patch([60 80 80 60],[0 0 h h],[1 0 0],'EdgeColor','none','FaceAlpha',0.9)
            patch([20 20 60 60],[3 0 0 1],[0 1 0],'EdgeColor','none','FaceAlpha',0.5)
            plot(ISOx,ISOy,'ok','MarkerSize',15,'MarkerFaceColor','k')
            
            % text on dots
            DISx = -ones(numel(ISOx),1)*1.5;
            for i = 1:numel(ISOx)
                cISOx = ISOx(i,1);
                cISOy = ISOy(i,1);
                cDISx = DISx(i,1);
                cSTRG = {['n = ',num2str(ISO{i,'Occurrences'},2)]; ...
                    ['\mu = ',char(duration(0,ISO{i,'Mean'},0))]; ...
                    ['\sigma = ',char(duration(0,ISO{i,'DevStd'},0))]};
                plot(repmat(cISOx,1,2),[0,cISOy],'k','LineWidth',2)
                text(cISOx+cDISx,cISOy+0.075*h,cSTRG,'FontSize',20)
            end
            clear cISOx cISOy cDISx
            
            % other plotting stuff
            xticks(setdiff(xticks,[0,10,70,80]))
            xlabel('angle (deg)')
            ylabel( 'time (min)')
            title({'ISO chart'; ...
                ['(total time = ',char(duration(0,0,numel(theta)*t0)), ...
                ', static total time = ',char(duration(0,sum(ISOy),0)),')']})
            set(gca,'FontSize',25)
        end

        % Button pushed function: PIEButton
        function PIEButtonPushed(app, event)
            % getting ExelObj as an ExelCompressed one
            obj = ExelCompressed(app.ExelObj);
            
            % getting sampling frequency and computing period
            sf = obj(1,1).SamplingFrequency;
            t0 = 1/sf;
            
            % setting thresholds
            omega_th = 10; % deg/s
            time_th = 4; % s
            
            % synchronizing objects
            synchronize(obj,inf,false);
            
            % computing angles with projection algorithm
            theta_hum = filterImuData(projection(obj(1,1)),sf);
            theta_thx = filterImuData(projection(obj(2,1)),sf);
            h = numel(theta_hum);
            
            % computing joint angle as a sum of the two above
            theta = abs(theta_hum + theta_thx);
            
            % computing angular velocity
            omega = discDerivative(theta,t0);
            
            % getting under thresholds indexes (static position)
            ind = (abs(omega) < omega_th)'; % row array
            
            % getting ind rises and falls
            rises = strfind(ind,[0,1])+1;
            falls = strfind(ind,[1,0])+1;
            if isempty(rises),rises = 1;end
            if isempty(falls),falls = h;end
            if falls(1,1) < rises(1,1)
                rises = [1,rises];
            end
            if rises(1,end) > falls(1,end)
                falls = [falls,h];
            end
            
            % now we have a static period between a rise and a fall index,
            % so we can compute static period durations as:
            times = (falls-rises)*t0;
            
            % getting only periods longer than 4 seconds
            times_ok_ind = (times>time_th)'; % col array
            rises_ok_ind = rises(1,times_ok_ind)';
            falls_ok_ind = falls(1,times_ok_ind)';
            times = times(times_ok_ind)'./60; % converion from s to min
            
            % preallocating
            theta_ok = [];
            
            % cycling on periods
            for i = 1:numel(times)
                % getting current rise and fall indexes
                cRise = rises_ok_ind(i,1);
                cFall = falls_ok_ind(i,1);
                
                % getting current angle as the mean in this period
                % getting also current period duration
                theta_ok = [theta_ok;theta(cRise:cFall)]; %#okAGROW
            end
            clear cRise cFall
            
            % creating a new figure for the PIE
            figure('Position',get(0,'ScreenSize'))
            
            % colors
            c = [0,1,0; ...
                1,1,0; ...
                1,0.6471,0; ...
                1,0,0];
            
            % legend
            l = {'\theta<20'; ...
                '20\leq\theta<60'; ...
                '60\leq\theta<90'; ...
                '\theta\geq90'};
            
            % first subplot: total
            subplot(1,2,1)
            
            % computing pie data
            PIEdata = [nnz(theta < 20), ...
                nnz(theta >= 20 & theta < 60), ...
                nnz(theta >= 60 & theta < 90), ...
                nnz(theta >= 90)] ./ numel(theta);
            zero_elements = PIEdata == 0;
            
            % plotting the pie chart
            warning('off','MATLAB:pie:NonPositiveData')
            p = pie(PIEdata);
            warning('on','MATLAB:pie:NonPositiveData')
            c_ok = c; l_ok = l;
            c_ok(zero_elements,:) = []; l_ok(zero_elements,:) = [];
            k = 1;
            for i = 1:1:numel(p)
                if isa(p(1,i),'matlab.graphics.primitive.Patch')
                    p(1,i).FaceColor = c_ok(k,:);
                    k = k + 1;
                else
                    p(1,i).FontSize = 25;
                end
            end
            
            % title
            time_tot = duration(0,0,numel(theta)*t0);
            tit = title({'PIE chart total';['(total time = ',char(time_tot),')']});
            tit.Position(1,2) = tit.Position(1,2) + 0.2;
            
            % legend
            warning('off','MATLAB:legend:IgnoringExtraEntries')
            leg = legend(l_ok,'Location','south','Orientation','horizontal');
            leg.Position(1,2) = leg.Position(1,2) - 0.2;
            warning('on','MATLAB:legend:IgnoringExtraEntries')
            
            % setting all stuff a bit bigger thanks
            set(gca,'FontSize',25)
            
            % second subplot: ISO
            subplot(1,2,2)
            
            % computing pie data
            PIEdata = [nnz(theta_ok < 20), ...
                nnz(theta_ok >= 20 & theta_ok < 60), ...
                nnz(theta_ok >= 60 & theta_ok < 90), ...
                nnz(theta_ok >= 90)] ./ numel(theta_ok);
            zero_elements = PIEdata == 0;
            
            % creating an empty pie if no static data
            if all(not(zero_elements))
                PIEdata = [1,0,0,0];
                zero_elements = PIEdata == 0;
                c(1,:) = [0.5,0.5,0.5];
                l{1,1} = 'No static data available';
                noDataFlag = true;
            else
                noDataFlag = false;
            end
            
            % plotting the pie chart
            warning('off','MATLAB:pie:NonPositiveData')
            p = pie(PIEdata);
            warning('on','MATLAB:pie:NonPositiveData')
            c_ok = c; l_ok = l;
            c_ok(zero_elements,:) = []; l_ok(zero_elements,:) = [];
            k = 1;
            for i = 1:1:numel(p)
                if isa(p(1,i),'matlab.graphics.primitive.Patch')
                    p(1,i).FaceColor = c_ok(k,:);
                    k = k + 1;
                else
                    if noDataFlag
                        p(1,i).String = '';
                    else
                        p(1,i).FontSize = 25;
                    end
                end
            end
            
            % title
            time_tot_stat = duration(0,0,numel(theta_ok)*t0);
            tit = title({'PIE chart static angles'; ...
                ['(total time = ',char(time_tot_stat),')']});
            tit.Position(1,2) = tit.Position(1,2) + 0.2;
            
            % legend
            warning('off','MATLAB:legend:IgnoringExtraEntries')
            leg = legend(l_ok,'Location','south','Orientation','horizontal');
            leg.Position(1,2) = leg.Position(1,2) - 0.2;
            warning('on','MATLAB:legend:IgnoringExtraEntries')
            
            % setting all stuff a bit bigger thanks
            set(gca,'FontSize',25)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            switch app.ButtonGroup.SelectedObject.Text
                case 'Projection1'
                    app.Algorithm = @projection;
                case 'Projection2'
                    app.Algorithm = @projection2mod;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MainAcquisition';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel1 = uipanel(app.UIFigure);
            app.Panel1.Title = 'EXEL NAMES & SEGMENTS';
            app.HomerDropDownLabel = uilabel(app.Panel1);
            app.HomerDropDownLabel.Text = 'Homer';
            app.HomerDropDown = uidropdown(app.Panel1);
            app.HomerDropDown.Items = {'Select...'};
            app.HomerDropDown.ValueChangedFcn = createCallbackFcn(app, @HomerDropDownValueChanged, true);
            app.HomerDropDown.Enable = 'off';
            app.HomerDropDown.Value = 'Select...';
            app.ThoraxDropDownLabel = uilabel(app.Panel1);
            app.ThoraxDropDownLabel.Text = 'Thorax';
            app.ThoraxDropDown = uidropdown(app.Panel1);
            app.ThoraxDropDown.Items = {'Select...'};
            app.ThoraxDropDown.ValueChangedFcn = createCallbackFcn(app, @ThoraxDropDownValueChanged, true);
            app.ThoraxDropDown.Enable = 'off';
            app.ThoraxDropDown.Value = 'Select...';
            app.HomerSwitch = uiswitch(app.Panel1, 'slider');
            app.HomerSwitch.ValueChangedFcn = createCallbackFcn(app, @HomerSwitchValueChanged, true);
            app.HomerSwitch.Enable = 'off';
            app.ThoraxSwitch = uiswitch(app.Panel1, 'slider');
            app.ThoraxSwitch.ValueChangedFcn = createCallbackFcn(app, @ThoraxSwitchValueChanged, true);
            app.ThoraxSwitch.Enable = 'off';
            app.SensorizedSideListBoxLabel = uilabel(app.Panel1);
            app.SensorizedSideListBoxLabel.Text = {'Sensorized'; 'Side'};
            app.SensorizedSideListBox = uilistbox(app.Panel1);
            app.SensorizedSideListBox.Items = {'Right', 'Left'};
            app.SensorizedSideListBox.Enable = 'off';
            app.SensorizedSideListBox.Value = 'Right';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'OPTIONS';
            app.AutoStopSwitch = uiswitch(app.Panel2, 'slider');
            app.AutoStopSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoStopSwitchValueChanged, true);
            app.AutoStopSwitch.Enable = 'off';
            app.AutoStopEditFieldLabel = uilabel(app.Panel2);
            app.AutoStopEditFieldLabel.Text = 'AutoStop (s)';
            app.AutoStopEditField = uieditfield(app.Panel2, 'text');
            app.AutoStopEditField.ValueChangedFcn = createCallbackFcn(app, @AutoStopEditFieldValueChanged, true);
            app.AutoStopEditField.Enable = 'off';
            app.AutoStopEditField.Value = 'Never Ending';
            app.SubjectEditFieldLabel = uilabel(app.Panel2);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.Panel2, 'text');
            app.SubjectEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectEditFieldValueChanged, true);
            app.SubjectEditField.Enable = 'off';
            app.SubjectEditField.Value = 'Not Specified';
            app.GoldStandardEditFieldLabel = uilabel(app.Panel2);
            app.GoldStandardEditFieldLabel.Text = 'GoldStandard';
            app.GoldStandardEditField = uieditfield(app.Panel2, 'text');
            app.GoldStandardEditField.ValueChangedFcn = createCallbackFcn(app, @GoldStandardEditFieldValueChanged, true);
            app.GoldStandardEditField.Enable = 'off';
            app.GoldStandardEditField.Value = 'Not Specified';
            app.CommentsTextAreaLabel = uilabel(app.Panel2);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.Panel2);
            app.CommentsTextArea.Enable = 'off';
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Icon = 'bluetooth_24.png';
            app.ScanButton.Enable = 'off';
            app.ScanButton.Text = 'Scan';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'run_24.png';
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'stop_24.png';
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save_24.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = 'plot_24.png';
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.ISOButton = uibutton(app.UIFigure, 'push');
            app.ISOButton.ButtonPushedFcn = createCallbackFcn(app, @ISOButtonPushed, true);
            app.ISOButton.Icon = 'iso_24.png';
            app.ISOButton.Enable = 'off';
            app.ISOButton.Text = 'ISO';
            app.PIEButton = uibutton(app.UIFigure, 'push');
            app.PIEButton.ButtonPushedFcn = createCallbackFcn(app, @PIEButtonPushed, true);
            app.PIEButton.Icon = 'pie_24.png';
            app.PIEButton.Enable = 'off';
            app.PIEButton.Text = 'PIE';
            app.HomerUIAxes = uiaxes(app.UIFigure);
            title(app.HomerUIAxes, 'Homer')
            xlabel(app.HomerUIAxes, 'time (s)')
            ylabel(app.HomerUIAxes, 'angle (deg)')
            app.HomerUIAxes.XLim = [0 10];
            app.HomerUIAxes.YLim = [0 180];
            app.HomerUIAxes.YTick = [0 30 60 90 120 150 180];
            app.ThoraxUIAxes = uiaxes(app.UIFigure);
            title(app.ThoraxUIAxes, 'Thorax')
            xlabel(app.ThoraxUIAxes, 'time (s)')
            ylabel(app.ThoraxUIAxes, 'angle (deg)')
            app.ThoraxUIAxes.XLim = [0 10];
            app.ThoraxUIAxes.YLim = [-90 90];
            app.ThoraxUIAxes.YTick = [-90 -60 -30 0 30 60 90];
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Projection1Button = uitogglebutton(app.ButtonGroup);
            app.Projection1Button.Enable = 'off';
            app.Projection1Button.Text = 'Projection1';
            app.Projection1Button.Value = true;
            app.Projection2Button = uitogglebutton(app.ButtonGroup);
            app.Projection2Button.Enable = 'off';
            app.Projection2Button.Text = 'Projection2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MainAcquisition

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end